classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        plotButton                  matlab.ui.control.Button
        selectsignalsDropDownLabel  matlab.ui.control.Label
        selectsignalsDropDown       matlab.ui.control.DropDown
        energyvalueEditFieldLabel   matlab.ui.control.Label
        energyvalueEditField        matlab.ui.control.NumericEditField
        powersignalEditFieldLabel   matlab.ui.control.Label
        powersignalEditField        matlab.ui.control.NumericEditField
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            
            simout_one = sim("SS1");
            
            simout_two = sim("SS2");
            
            simout_three = sim("SS3");
            
            simout_four = sim("SS4");
            
            simout_five = sim("SS5");
            
            simout_six = sim("SS6");
            
            x = 0;
            y = 0;


            if (strcmp(app.selectsignalsDropDown.Value, 'sine signal'));
                plot(app.UIAxes3,simout_one.inputsignal.Time,simout_one.inputsignal.Data);
                plot(app.UIAxes2,simout_one.sinesignal.Time,simout_one.sinesignal.Data);
                plot(app.UIAxes,x,y);
                VoP = 2.114;
                app.powersignalEditField.Value = VoP;
                bb = 0;
                app.energyvalueEditField.Value = bb;
            end
            if (strcmp(app.selectsignalsDropDown.Value, 'ramp signal'));
                plot(app.UIAxes3,simout_two.rampsignal.Time,simout_two.rampsignal.Data);
                plot(app.UIAxes,simout_two.rampsignalone.Time,simout_two.rampsignalone.Data);
                plot(app.UIAxes2,x,y);
                VoE = 333.3;
                app.energyvalueEditField.Value = VoE;
                aa = 0;
                app.powersignalEditField.Value = aa;
            end   
            if (strcmp(app.selectsignalsDropDown.Value, 'step signal'));
                plot(app.UIAxes3,simout_three.stepsignal.Time,simout_three.stepsignal.Data);
                plot(app.UIAxes2,simout_three.stepsignalone.Time,simout_three.stepsignalone.Data);
                plot(app.UIAxes,x,y);
                VoR = 5;
                app.powersignalEditField.Value = VoR;
                cc = 0;
                app.energyvalueEditField.Value = cc;
                
            end
            
            if (strcmp(app.selectsignalsDropDown.Value, 'exponential signal'));
                plot(app.UIAxes3,simout_four.expsignal.Time,simout_four.expsignal.Data);
                plot(app.UIAxes2,simout_four.expout.Time,simout_four.expout.Data);
                plot(app.UIAxes,x,y);
                VoS = 14.25;
                app.powersignalEditField.Value = VoS;
                dd = 0;
                app.energyvalueEditField.Value = dd;
                
                
            end
            if (strcmp(app.selectsignalsDropDown.Value, 'noise signal'));
                plot(app.UIAxes3,simout_five.noisesignal.Time,simout_five.noisesignal.Data);
                plot(app.UIAxes,simout_five.noisesignalout.Time,simout_five.noisesignalout.Data);
                plot(app.UIAxes2,x,y);
                VoF = 14.58;
                app.energyvalueEditField.Value = VoF;
                ee = 0;
                app.powersignalEditField.Value = ee;
                
            end
            
            if(strcmp(app.selectsignalsDropDown.Value, 'cosine signal'));
                plot(app.UIAxes3,simout_six.cosine.Time,simout_six.cosine.Data);
                plot(app.UIAxes2,simout_six.cosineout.Time,simout_six.cosineout.Data);
                plot(app.UIAxes,x,y);
                VoQ = 2.189;
                app.powersignalEditField.Value = VoQ;
                ll = 0;
                app.energyvalueEditField.Value = ll;
                
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'energy signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'power signal')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'input signal')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.plotButton = uibutton(app.UIFigure, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.selectsignalsDropDownLabel = uilabel(app.UIFigure);
            app.selectsignalsDropDownLabel.Text = 'select signals';
            app.selectsignalsDropDown = uidropdown(app.UIFigure);
            app.selectsignalsDropDown.Items = {'sine signal', 'cosine signal', 'step signal', 'ramp signal', 'exponential signal', 'noise signal', 'rectangular signal'};
            app.selectsignalsDropDown.Value = 'sine signal';
            app.energyvalueEditFieldLabel = uilabel(app.UIFigure);
            app.energyvalueEditFieldLabel.Text = 'energy value';
            app.energyvalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.powersignalEditFieldLabel = uilabel(app.UIFigure);
            app.powersignalEditFieldLabel.Text = 'power signal';
            app.powersignalEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end